classdef HydroScan < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        MotorsTab                     matlab.ui.container.Tab
        EditField_tav                 matlab.ui.control.EditField
        EditField_trv                 matlab.ui.control.EditField
        TButton_Minus                 matlab.ui.control.Button
        TButton_Plus                  matlab.ui.control.Button
        Lamp_Motor                    matlab.ui.control.Lamp
        ConnectButton_Motor           matlab.ui.control.StateButton
        GohomeButton                  matlab.ui.control.Button
        EditField_zav                 matlab.ui.control.EditField
        EditField_yav                 matlab.ui.control.EditField
        EditField_xav                 matlab.ui.control.EditField
        EditField_zrv                 matlab.ui.control.EditField
        EditField_yrv                 matlab.ui.control.EditField
        EditField_xrv                 matlab.ui.control.EditField
        SethomeButton                 matlab.ui.control.Button
        ZButton_Minus                 matlab.ui.control.Button
        ZButton_Plus                  matlab.ui.control.Button
        YButton_Minus                 matlab.ui.control.Button
        YButton_Plus                  matlab.ui.control.Button
        XButton_Minus                 matlab.ui.control.Button
        XButton_Plus                  matlab.ui.control.Button
        PicoTab                       matlab.ui.container.Tab
        SliderYaxis                   matlab.ui.control.Slider
        SliderAverages                matlab.ui.control.Slider
        Lamp_Pico                     matlab.ui.control.Lamp
        ConnectButton_Pico            matlab.ui.control.StateButton
        PulseButton                   matlab.ui.control.StateButton
        PulsePanel                    matlab.ui.container.Panel
        NumberofcyclesEditField       matlab.ui.control.EditField
        NumberofcyclesEditFieldLabel  matlab.ui.control.Label
        FrequencyMHzEditField         matlab.ui.control.EditField
        FrequencyMHzEditFieldLabel    matlab.ui.control.Label
        UIAxes_Pico                   matlab.ui.control.UIAxes
        ScanTab                       matlab.ui.container.Tab
    end


    properties (Access = private)
        Motor % MotorXYZ class
        Pico % PicoScope class
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: ConnectButton_Pico
        function ConnectButton_PicoValueChanged(app, event)
            value = app.ConnectButton_Pico.Value;
            if value
                app.Pico = PicoScope;
                app.Pico.connect();
                app.Lamp_Pico.Color = [0 1 0];
                app.Pico.configure_scope();
                
                fc = str2num(app.FrequencyMHzEditField.Value)*1e6;
                nc = str2num(app.NumberofcyclesEditField.Value);
                
                app.Pico.configure_generator(fc,nc);
            else
                app.Pico.disconnect();
                app.Lamp_Pico.Color = [1 0 0];
            end
        end

        % Value changed function: PulseButton
        function PulseButtonValueChanged(app, event)
            [sold, tin] = app.Pico.pulse();
            t=(1:length(sold))*tin/1e3;

            nav = 2^(10*app.SliderAverages.Value);
            ylimit = 2^(10*(app.SliderYaxis.Value-1));
            value = app.PulseButton.Value;

            while value
                [snew, tin] = app.Pico.pulse();
                s = (snew + (nav-1)*sold)/nav;
                mxs = max(abs(s))*ylimit;
                mns = mean(s);
                
                plot(app.UIAxes_Pico, t, s),
                ylim(app.UIAxes_Pico,[mns-mxs mns+mxs])

                sold = s;
    
                nav = 2^(10*app.SliderAverages.Value);
                ylimit = 2^(10*(app.SliderYaxis.Value-1));
                value = app.PulseButton.Value;
                
                drawnow
            end

        end

        % Value changed function: ConnectButton_Motor
        function ConnectButton_MotorValueChanged(app, event)
            value = app.ConnectButton_Motor.Value;
            if value
                app.Motor = MotorXYZT;
                app.Motor.connect('XXX');
                app.Lamp_Motor.Color = [0 1 0];
            else
                app.Motor.disconnect();
                app.Lamp_Motor.Color = [1 0 0];
            end
        end

        % Button pushed function: SethomeButton
        function SethomeButtonPushed(app, event)
            app.Motor.sethome();
            app.EditField_xav.Value = sprintf('%7.3f', app.Motor.curloc(1));
            app.EditField_yav.Value = sprintf('%7.3f', app.Motor.curloc(2));
            app.EditField_zav.Value = sprintf('%7.3f', app.Motor.curloc(3));
            app.EditField_tav.Value = sprintf('%7.3f', app.Motor.curloc(4));
        end

        % Button pushed function: XButton_Plus
        function XButton_PlusPushed(app, event)
            motornum = 1;
            relative_movement = abs( str2num(app.EditField_xrv.Value) );
            app.Motor.move(motornum,relative_movement)
            app.EditField_xav.Value = sprintf('%7.3f', app.Motor.curloc(motornum));
        end

        % Button pushed function: XButton_Minus
        function XButton_MinusPushed(app, event)
            motornum = 1;
            relative_movement = -abs( str2num(app.EditField_xrv.Value) );
            app.Motor.move(motornum,relative_movement)
            app.EditField_xav.Value = sprintf('%7.3f', app.Motor.curloc(motornum));
        end

        % Button pushed function: YButton_Plus
        function YButton_PlusPushed(app, event)
            motornum = 2;
            relative_movement = abs( str2num(app.EditField_yrv.Value) );
            app.Motor.move(motornum,relative_movement)
            app.EditField_yav.Value = sprintf('%7.3f', app.Motor.curloc(motornum));
        end

        % Button pushed function: YButton_Minus
        function YButton_MinusPushed(app, event)
            motornum = 2;
            relative_movement = -abs( str2num(app.EditField_yrv.Value) );
            app.Motor.move(motornum,relative_movement)
            app.EditField_yav.Value = sprintf('%7.3f', app.Motor.curloc(motornum));
        end

        % Button pushed function: ZButton_Plus
        function ZButton_PlusPushed(app, event)
            motornum = 3;
            relative_movement = abs( str2num(app.EditField_zrv.Value) );
            app.Motor.move(motornum,relative_movement)
            app.EditField_zav.Value = sprintf('%7.3f', app.Motor.curloc(motornum));
        end

        % Button pushed function: ZButton_Minus
        function ZButton_MinusPushed(app, event)
            motornum = 3;
            relative_movement = abs( str2num(app.EditField_zrv.Value) );
            app.Motor.move(motornum,relative_movement)
            app.EditField_zav.Value = sprintf('%7.3f', app.Motor.curloc(motornum));
        end

        % Button pushed function: TButton_Plus
        function TButton_PlusPushed(app, event)
            motornum = 4;
            relative_movement = abs( str2num(app.EditField_trv.Value) );
            app.Motor.move(motornum,relative_movement)
            app.EditField_tav.Value = sprintf('%7.1f', app.Motor.curloc(motornum));
        end

        % Button pushed function: TButton_Minus
        function TButton_MinusPushed(app, event)
            motornum = 4;
            relative_movement = -abs( str2num(app.EditField_trv.Value) );
            app.Motor.move(motornum,relative_movement)
            app.EditField_tav.Value = sprintf('%7.1f', app.Motor.curloc(motornum));
        end

        % Button pushed function: GohomeButton
        function GohomeButtonPushed(app, event)
            app.Motor.gohome()
            app.EditField_xav.Value = sprintf('%7.3f', app.Motor.curloc(1));
            app.EditField_yav.Value = sprintf('%7.3f', app.Motor.curloc(2));
            app.EditField_zav.Value = sprintf('%7.3f', app.Motor.curloc(3));
            app.EditField_tav.Value = sprintf('%7.3f', app.Motor.curloc(4));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.MotorsTab = uitab(app.TabGroup);
            app.MotorsTab.Title = 'Motors';
            app.XButton_Plus = uibutton(app.MotorsTab, 'push');
            app.XButton_Plus.ButtonPushedFcn = createCallbackFcn(app, @XButton_PlusPushed, true);
            app.XButton_Plus.Tag = 'xp';
            app.XButton_Plus.Text = 'X+';
            app.XButton_Minus = uibutton(app.MotorsTab, 'push');
            app.XButton_Minus.ButtonPushedFcn = createCallbackFcn(app, @XButton_MinusPushed, true);
            app.XButton_Minus.Tag = 'xm';
            app.XButton_Minus.Text = 'X-';
            app.YButton_Plus = uibutton(app.MotorsTab, 'push');
            app.YButton_Plus.ButtonPushedFcn = createCallbackFcn(app, @YButton_PlusPushed, true);
            app.YButton_Plus.Tag = 'yp';
            app.YButton_Plus.Text = 'Y+';
            app.YButton_Minus = uibutton(app.MotorsTab, 'push');
            app.YButton_Minus.ButtonPushedFcn = createCallbackFcn(app, @YButton_MinusPushed, true);
            app.YButton_Minus.Tag = 'ym';
            app.YButton_Minus.Text = 'Y-';
            app.ZButton_Plus = uibutton(app.MotorsTab, 'push');
            app.ZButton_Plus.ButtonPushedFcn = createCallbackFcn(app, @ZButton_PlusPushed, true);
            app.ZButton_Plus.Tag = 'zp';
            app.ZButton_Plus.Text = 'Z+';
            app.ZButton_Minus = uibutton(app.MotorsTab, 'push');
            app.ZButton_Minus.ButtonPushedFcn = createCallbackFcn(app, @ZButton_MinusPushed, true);
            app.ZButton_Minus.Tag = 'zm';
            app.ZButton_Minus.Text = 'Z-';
            app.SethomeButton = uibutton(app.MotorsTab, 'push');
            app.SethomeButton.ButtonPushedFcn = createCallbackFcn(app, @SethomeButtonPushed, true);
            app.SethomeButton.Text = 'Set home';
            app.EditField_xrv = uieditfield(app.MotorsTab, 'text');
            app.EditField_xrv.Tag = 'xrv';
            app.EditField_xrv.Value = '1.0';
            app.EditField_yrv = uieditfield(app.MotorsTab, 'text');
            app.EditField_yrv.Tag = 'yrv';
            app.EditField_yrv.Value = '1.0';
            app.EditField_zrv = uieditfield(app.MotorsTab, 'text');
            app.EditField_zrv.Tag = 'zrv';
            app.EditField_zrv.Value = '1.0';
            app.EditField_xav = uieditfield(app.MotorsTab, 'text');
            app.EditField_xav.Tag = 'xav';
            app.EditField_xav.Editable = 'off';
            app.EditField_xav.Enable = 'off';
            app.EditField_xav.Value = '0.0';
            app.EditField_yav = uieditfield(app.MotorsTab, 'text');
            app.EditField_yav.Tag = 'yav';
            app.EditField_yav.Editable = 'off';
            app.EditField_yav.Enable = 'off';
            app.EditField_yav.Value = '0.0';
            app.EditField_zav = uieditfield(app.MotorsTab, 'text');
            app.EditField_zav.Tag = 'zav';
            app.EditField_zav.Editable = 'off';
            app.EditField_zav.Enable = 'off';
            app.EditField_zav.Value = '0.0';
            app.GohomeButton = uibutton(app.MotorsTab, 'push');
            app.GohomeButton.ButtonPushedFcn = createCallbackFcn(app, @GohomeButtonPushed, true);
            app.GohomeButton.Text = 'Go home';
            app.ConnectButton_Motor = uibutton(app.MotorsTab, 'state');
            app.ConnectButton_Motor.ValueChangedFcn = createCallbackFcn(app, @ConnectButton_MotorValueChanged, true);
            app.ConnectButton_Motor.Text = 'Connect';
            app.Lamp_Motor = uilamp(app.MotorsTab);
            app.TButton_Plus = uibutton(app.MotorsTab, 'push');
            app.TButton_Plus.ButtonPushedFcn = createCallbackFcn(app, @TButton_PlusPushed, true);
            app.TButton_Plus.Tag = 'zp';
            app.TButton_Plus.Text = 'T+';
            app.TButton_Minus = uibutton(app.MotorsTab, 'push');
            app.TButton_Minus.ButtonPushedFcn = createCallbackFcn(app, @TButton_MinusPushed, true);
            app.TButton_Minus.Tag = 'zm';
            app.TButton_Minus.Text = 'T-';
            app.EditField_trv = uieditfield(app.MotorsTab, 'text');
            app.EditField_trv.Tag = 'zrv';
            app.EditField_trv.Value = '10.0';
            app.EditField_tav = uieditfield(app.MotorsTab, 'text');
            app.EditField_tav.Tag = 'zav';
            app.EditField_tav.Editable = 'off';
            app.EditField_tav.Enable = 'off';
            app.EditField_tav.Value = '0.0';
            app.PicoTab = uitab(app.TabGroup);
            app.PicoTab.Title = 'Pico';
            app.UIAxes_Pico = uiaxes(app.PicoTab);
            xlabel(app.UIAxes_Pico, 'Time (\mus)')
            app.PulsePanel = uipanel(app.PicoTab);
            app.PulsePanel.Title = 'Pulse';
            app.FrequencyMHzEditFieldLabel = uilabel(app.PulsePanel);
            app.FrequencyMHzEditFieldLabel.Text = 'Frequency (MHz)';
            app.FrequencyMHzEditField = uieditfield(app.PulsePanel, 'text');
            app.FrequencyMHzEditField.Value = '10.00';
            app.NumberofcyclesEditFieldLabel = uilabel(app.PulsePanel);
            app.NumberofcyclesEditFieldLabel.Text = 'Number of cycles';
            app.NumberofcyclesEditField = uieditfield(app.PulsePanel, 'text');
            app.NumberofcyclesEditField.Value = '10.00';
            app.PulseButton = uibutton(app.PicoTab, 'state');
            app.PulseButton.ValueChangedFcn = createCallbackFcn(app, @PulseButtonValueChanged, true);
            app.PulseButton.Text = 'Pulse';
            app.ConnectButton_Pico = uibutton(app.PicoTab, 'state');
            app.ConnectButton_Pico.ValueChangedFcn = createCallbackFcn(app, @ConnectButton_PicoValueChanged, true);
            app.ConnectButton_Pico.Text = 'Connect';
            app.Lamp_Pico = uilamp(app.PicoTab);
            app.SliderAverages = uislider(app.PicoTab);
            app.SliderAverages.Limits = [0 1];
            app.SliderAverages.MajorTicks = [];
            app.SliderAverages.MinorTicks = [];
            app.SliderYaxis = uislider(app.PicoTab);
            app.SliderYaxis.Limits = [0 1];
            app.SliderYaxis.MajorTicks = [];
            app.SliderYaxis.MinorTicks = [];
            app.SliderYaxis.Value = 1;
            app.ScanTab = uitab(app.TabGroup);
            app.ScanTab.Title = 'Scan';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HydroScan

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end